classdef Flow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel_2                         matlab.ui.container.Panel
        PointslocationSlider            matlab.ui.control.Slider
        PointslocationSliderLabel       matlab.ui.control.Label
        PointstomodifySpinner           matlab.ui.control.Spinner
        PointstomodifyLabel             matlab.ui.control.Label
        DefinethenumberofpointsSpinner  matlab.ui.control.Spinner
        DefinethenumberofpointsSpinnerLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        ComputeButton                   matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        XPoints % X value of line points
        YPoints % Y value of line points
        XLine   % X spline interpolating the points
        YLine   % Y splie interpolation the points
        hSpline %
        hMirror %
        hP1     %
        hPn     %
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Define the initial line:
            app.XPoints = [0 3];
            app.YPoints = [0.25 0.5];
            app.XLine   = linspace(app.XPoints(1), app.XPoints(end), 100);
            app.YLine   = interp1(app.XPoints, app.YPoints, app.XLine, 'spline');
            app.PointslocationSlider.Value = app.YLine(1);
            app.PointstomodifySpinner.Value = 1;
            app.DefinethenumberofpointsSpinner.Value = 2;
            
            % Plot the line:
            index = app.PointstomodifySpinner.Value;
            app.hSpline = area(app.UIAxes,app.XLine,app.YLine, 'SeriesIndex', 1, 'FaceAlpha', 0.5);
            hold(app.UIAxes, 'on')
            app.hMirror = area(app.UIAxes,app.XLine,-app.YLine, 'SeriesIndex', 1, 'FaceAlpha', 0.5);
            app.hPn = scatter(app.UIAxes, app.XPoints, app.YPoints, 30, 'filled', 'o', 'SeriesIndex', 1);
            app.hP1 = scatter(app.UIAxes, app.XLine(index), app.YLine(index), 30, 'filled', 'o', 'SeriesIndex', 2);
            hold(app.UIAxes, 'off')            

        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            
            cla(app.Panel)
            cla(app.Panel_2)

            % Define parameter:
            Uinf = 1.0;
            Scaling = Uinf * app.YLine(1);

            % Define the mesh of the line
            dl = zeros(7,200);
            for i = 1:99
                dl(:,i) = [2; app.XLine(i); app.XLine(i+1); ...
                              app.YLine(i); app.YLine(i+1); ...
                              0;1];
            end
            dl(:,100) = [2; app.XLine(end); app.XLine(end); app.YLine(end); -app.YLine(end); 0; 1];
            for i = 1:99
                dl(:,i+100) = [2;  app.XLine(101-i);  app.XLine(100-i); ...
                                  -app.YLine(101-i); -app.YLine(100-i); ...
                              0;1];
            end
            dl(:,end) = [2; app.XLine(1); app.XLine(1); -app.YLine(1); app.YLine(1); 0;1];
            
            % Define the model:
            psi = 1;
            syms psi(x,y)      
            pdeeq = laplacian(psi, [x y]) == 0;
            coeffs = pdeCoefficients(pdeeq, psi);
            model = createpde;
            geometryFromEdges(model,dl);
            generateMesh(model);
            specifyCoefficients(model,'m',coeffs.m,'d',coeffs.d,'c',coeffs.c,'a',coeffs.a,'f',coeffs.f);    
            
            % Define boundary condition:
            applyBoundaryCondition(model,'dirichlet','Edge',1:99,'u',@(region,state) 1.0);
            applyBoundaryCondition(model,'dirichlet','Edge',101:199,'u',@(region,state) 0.0);

            % Solve PDE
            results = solvepde(model);
            u = Scaling * sqrt( results.XGradients.^2 + results.YGradients.^2);
            x = results.Mesh.Nodes(1,:)';
            y = results.Mesh.Nodes(2,:)';
            
            % Plot velocity:
            vel = pdeplot(model,'XYData',u);
            ax = vel.Parent;
            ax.Parent = app.Panel;
            
            p = pdegplot(model, "FaceLabels", "off");
            p.LineWidth = 2;
            p.Color = 'k';
            p.Parent = ax;

            hold(ax, "on")
            plot(ax, [0 3], [0 0], 'SeriesIndex', 2, 'LineStyle', '--', 'LineWidth', 2);

            xlabel(ax,"x (m)");
            ylabel(ax,"y (m)");
            ax.XLim = [-0.05*3.0 1.05*3.0];
            ax.YLim = [-1.05 1.05];
            hold(ax, "off")

            % Plot kinetic energy:
            F = scatteredInterpolant(x,y,u);
            ULine = F(app.XLine, 0.0*app.XLine);
            Ekin = 0.5*ULine.^2;
            P = (1-ULine.^2)*0.5;
            lU = plot(app.XLine, Ekin);
            axp = lU.Parent;
            axp.Parent = app.Panel_2;
            hold(axp, "on")           
            plot(lU.Parent, app.XLine, P);
            plot(lU.Parent, app.XLine, P+Ekin);
%             psiinterp = interpolateSolution(results,app.XLine,0.0*app.XLine);
        
        end

        % Value changing function: PointslocationSlider
        function PointslocationSliderValueChanging(app, event)
            value = event.Value;
            index = app.PointstomodifySpinner.Value;
            app.YPoints(index) = value;
            app.YLine = interp1(app.XPoints, app.YPoints, app.XLine, "spline");
            app.hSpline.YData = app.YLine;
            app.hMirror.YData = -app.YLine;
            app.hPn.YData = app.YPoints;
            app.hP1.YData = app.YPoints(index);
        end

        % Value changed function: PointstomodifySpinner
        function PointstomodifySpinnerValueChanged(app, event)
            value = app.PointstomodifySpinner.Value;
            app.PointstomodifySpinner.Limits = [1 app.DefinethenumberofpointsSpinner.Value];
            app.hP1.XData = app.XPoints(value);
            app.hP1.YData = app.YPoints(value);
        end

        % Value changed function: DefinethenumberofpointsSpinner
        function DefinethenumberofpointsSpinnerValueChanged(app, event)
            value = app.DefinethenumberofpointsSpinner.Value;
            index = app.PointstomodifySpinner.Value;
            app.PointstomodifySpinner.Limits = [1 value];
            app.YPoints = interp1(app.XPoints, app.YPoints, linspace(0.0,3.0,value), "spline");
            app.XPoints = linspace(0.0,3.0,value);
            app.hPn.XData = app.XPoints;
            app.hPn.YData = app.YPoints;
            app.hP1.XData = app.XPoints(index);
            app.hP1.YData = app.YPoints(index);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'x (m)')
            ylabel(app.UIAxes, 'y (m)')
            app.UIAxes.XLim = [0 3];
            app.UIAxes.YLim = [-1 1];
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.XTick = [0 1 2 3];
            app.UIAxes.XTickLabel = {'0'; '1'; '2'; '3'};
            app.UIAxes.YTick = [-1 0 1];
            app.UIAxes.ZTick = [];
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.Panel = uipanel(app.UIFigure);
            app.DefinethenumberofpointsSpinnerLabel = uilabel(app.UIFigure);
            app.DefinethenumberofpointsSpinnerLabel.Text = 'Define the number of points';
            app.DefinethenumberofpointsSpinner = uispinner(app.UIFigure);
            app.DefinethenumberofpointsSpinner.Limits = [2 100];
            app.DefinethenumberofpointsSpinner.ValueChangedFcn = createCallbackFcn(app, @DefinethenumberofpointsSpinnerValueChanged, true);
            app.DefinethenumberofpointsSpinner.Value = 2;
            app.PointstomodifyLabel = uilabel(app.UIFigure);
            app.PointstomodifyLabel.Text = 'Points to modify:';
            app.PointstomodifySpinner = uispinner(app.UIFigure);
            app.PointstomodifySpinner.Limits = [1 2];
            app.PointstomodifySpinner.ValueChangedFcn = createCallbackFcn(app, @PointstomodifySpinnerValueChanged, true);
            app.PointstomodifySpinner.Value = 1;
            app.PointslocationSliderLabel = uilabel(app.UIFigure);
            app.PointslocationSliderLabel.Text = 'Points location';
            app.PointslocationSlider = uislider(app.UIFigure);
            app.PointslocationSlider.Limits = [0 1];
            app.PointslocationSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.PointslocationSlider.MajorTickLabels = {'0', '0.25', '0.5', '0.75', '1'};
            app.PointslocationSlider.ValueChangingFcn = createCallbackFcn(app, @PointslocationSliderValueChanging, true);
            app.PointslocationSlider.MinorTicks = [];
            app.Panel_2 = uipanel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Flow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end